classdef DeltaZGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BoardImage                matlab.ui.control.Image
        TicTacToewithDeltaZLabel  matlab.ui.control.Label
        GameStateLabel            matlab.ui.control.Label
        ImageSpot1                matlab.ui.control.Image
        ImageSpot2                matlab.ui.control.Image
        ImageSpot3                matlab.ui.control.Image
        ImageSpot4                matlab.ui.control.Image
        ImageSpot5                matlab.ui.control.Image
        ImageSpot6                matlab.ui.control.Image
        ImageSpot7                matlab.ui.control.Image
        ImageSpot8                matlab.ui.control.Image
        ImageSpot9                matlab.ui.control.Image
        StartButton               matlab.ui.control.Button
        XRobotPanel               matlab.ui.container.Panel
        PortInput_X               matlab.ui.control.EditField
        SerialOutput_X            matlab.ui.control.TextArea
        PortNumberLabel_X         matlab.ui.control.Label
        ConnectButton_X           matlab.ui.control.Button
        SerialOutputLabel_X       matlab.ui.control.Label
        ORobotPanel               matlab.ui.container.Panel
        PortInput_O               matlab.ui.control.EditField
        SerialOutput_O            matlab.ui.control.TextArea
        PortNumberLabel_O         matlab.ui.control.Label
        ConnectButton_O           matlab.ui.control.Button
        SerialOutputLabel_O       matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
       frontEnd; 
       imageSourceX = "X.png";
       imageSourceO = "O.png";
       imageSourceB = "Empty.png";
       inGame = 0;
    end
    
    methods (Access = private)
        
        function updateView(app)
            updateBoard(app);
            
            if app.frontEnd.getConnectedX()
                app.ConnectButton_X.BackgroundColor = [0.39,0.83,0.07];
            else 
                app.ConnectButton_X.BackgroundColor = [1.00,0.00,0.00];
            end
            
            if app.frontEnd.getConnectedO()
                app.ConnectButton_O.BackgroundColor = [0.39,0.83,0.07];
            else 
                app.ConnectButton_O.BackgroundColor = [1.00,0.00,0.00];
            end
            
            app.GameStateLabel.Text = app.frontEnd.gameState;
            app.SerialOutput_O.Value = app.frontEnd.response_O;
            app.SerialOutput_X.Value = app.frontEnd.response_X;
            
            if app.frontEnd.gameState ~= "Press start to begin"
                app.StartButton.Enable = false;
                app.ResetButton.Enable = true;
            else 
                app.StartButton.Enable = true;
                app.ResetButton.Enable = false;
            end
            
            if extractAfter(app.frontEnd.gameState, 1) == " wins!"
                app.inGame = 0;
            end
        end
        
        function updateBoard(app)
            if app.frontEnd.getMarkAt(1) == 'X'
                app.ImageSpot1.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(1) == 'O'
                app.ImageSpot1.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot1.ImageSource = app.imageSourceB;
            end
            
            if app.frontEnd.getMarkAt(2) == 'X'
                app.ImageSpot2.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(2) == 'O'
                app.ImageSpot2.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot2.ImageSource = app.imageSourceB;
            end
            
            if app.frontEnd.getMarkAt(3) == 'X'
                app.ImageSpot3.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(3) == 'O'
                app.ImageSpot3.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot3.ImageSource = app.imageSourceB;
            end
            
            if app.frontEnd.getMarkAt(4) == 'X'
                app.ImageSpot4.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(4) == 'O'
                app.ImageSpot4.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot4.ImageSource = app.imageSourceB;
            end
            
            if app.frontEnd.getMarkAt(5) == 'X'
                app.ImageSpot5.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(5) == 'O'
                app.ImageSpot5.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot5.ImageSource = app.imageSourceB;
            end
            
            if app.frontEnd.getMarkAt(5) == 'X'
                app.ImageSpot5.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(5) == 'O'
                app.ImageSpot5.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot5.ImageSource = app.imageSourceB;
            end
            
            if app.frontEnd.getMarkAt(6) == 'X'
                app.ImageSpot6.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(6) == 'O'
                app.ImageSpot6.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot6.ImageSource = app.imageSourceB;
            end
            
            if app.frontEnd.getMarkAt(7) == 'X'
                app.ImageSpot7.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(7) == 'O'
                app.ImageSpot7.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot7.ImageSource = app.imageSourceB;
            end
            
            if app.frontEnd.getMarkAt(8) == 'X'
                app.ImageSpot8.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(8) == 'O'
                app.ImageSpot8.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot8.ImageSource = app.imageSourceB;
            end
            
            if app.frontEnd.getMarkAt(9) == 'X'
                app.ImageSpot9.ImageSource = app.imageSourceX;
            elseif app.frontEnd.getMarkAt(9) == 'O'
                app.ImageSpot9.ImageSource = app.imageSourceO;                
            else
                app.ImageSpot9.ImageSource = app.imageSourceB;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.frontEnd = DeltaZFrontEnd();
            
            app.ResetButton.Enable = false;
            app.updateView();
            addpath("C:\Users\koenignm\Documents\Spring2022\ME435\DeltaZProjectRepo\DeltaZ-RoboticsEngineering\MATLAB")
        end

        % Image clicked function: ImageSpot1
        function ImageSpot1Clicked(app, event)
            if app.inGame   
                app.frontEnd.move(1)
                app.updateView();
            end
        end

        % Image clicked function: ImageSpot2
        function ImageSpot2Clicked(app, event)
            if app.inGame               
                app.frontEnd.move(2)
                app.updateView(); 
            end
        end

        % Image clicked function: ImageSpot3
        function ImageSpot3Clicked(app, event)
            if app.inGame   
                app.frontEnd.move(3)
                app.updateView();
            end
        end

        % Image clicked function: ImageSpot4
        function ImageSpot4Clicked(app, event)
            if app.inGame   
                app.frontEnd.move(4)
                app.updateView();
            end
        end

        % Image clicked function: ImageSpot5
        function ImageSpot5Clicked(app, event)
            if app.inGame   
                app.frontEnd.move(5)
                app.updateView();
            end
        end

        % Image clicked function: ImageSpot6
        function ImageSpot6Clicked(app, event)
            if app.inGame   
                app.frontEnd.move(6)
                app.updateView();
            end
        end

        % Image clicked function: ImageSpot7
        function ImageSpot7Clicked(app, event)
            if app.inGame   
                app.frontEnd.move(7)
                app.updateView();
            end
        end

        % Image clicked function: ImageSpot8
        function ImageSpot8Clicked(app, event)
            if app.inGame   
                app.frontEnd.move(8)
                app.updateView();
            end
        end

        % Image clicked function: ImageSpot9
        function ImageSpot9Clicked(app, event)
            if app.inGame   
                app.frontEnd.move(9)
                app.updateView();       
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.inGame = 1;
            app.frontEnd.startGame()
            app.updateView();
        end

        % Button pushed function: ConnectButton_O
        function ConnectButton_OPushed(app, event)
            port = app.PortInput_O.Value;
            app.frontEnd.connectOnPort("O", port)
            app.updateView();
        end

        % Button pushed function: ConnectButton_X
        function ConnectButton_XPushed(app, event)
            port = app.PortInput_X.Value;
            app.frontEnd.connectOnPort("X", port)
            app.updateView();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.frontEnd = DeltaZFrontEnd();
            app.updateView();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BoardImage = uiimage(app.UIFigure);
            app.BoardImage.ImageSource = 'GameBoard.jpg';
            app.TicTacToewithDeltaZLabel = uilabel(app.UIFigure);
            app.TicTacToewithDeltaZLabel.Text = 'TicTacToe with DeltaZ';
            app.GameStateLabel = uilabel(app.UIFigure);
            app.GameStateLabel.Text = 'Game State Label';
            app.ImageSpot1 = uiimage(app.UIFigure);
            app.ImageSpot1.ImageClickedFcn = createCallbackFcn(app, @ImageSpot1Clicked, true);
            app.ImageSpot1.ImageSource = 'Empty.png';
            app.ImageSpot2 = uiimage(app.UIFigure);
            app.ImageSpot2.ImageClickedFcn = createCallbackFcn(app, @ImageSpot2Clicked, true);
            app.ImageSpot2.ImageSource = 'Empty.png';
            app.ImageSpot3 = uiimage(app.UIFigure);
            app.ImageSpot3.ImageClickedFcn = createCallbackFcn(app, @ImageSpot3Clicked, true);
            app.ImageSpot3.ImageSource = 'Empty.png';
            app.ImageSpot4 = uiimage(app.UIFigure);
            app.ImageSpot4.ImageClickedFcn = createCallbackFcn(app, @ImageSpot4Clicked, true);
            app.ImageSpot4.ImageSource = 'Empty.png';
            app.ImageSpot5 = uiimage(app.UIFigure);
            app.ImageSpot5.ImageClickedFcn = createCallbackFcn(app, @ImageSpot5Clicked, true);
            app.ImageSpot5.ImageSource = 'Empty.png';
            app.ImageSpot6 = uiimage(app.UIFigure);
            app.ImageSpot6.ImageClickedFcn = createCallbackFcn(app, @ImageSpot6Clicked, true);
            app.ImageSpot6.ImageSource = 'Empty.png';
            app.ImageSpot7 = uiimage(app.UIFigure);
            app.ImageSpot7.ImageClickedFcn = createCallbackFcn(app, @ImageSpot7Clicked, true);
            app.ImageSpot7.ImageSource = 'Empty.png';
            app.ImageSpot8 = uiimage(app.UIFigure);
            app.ImageSpot8.ImageClickedFcn = createCallbackFcn(app, @ImageSpot8Clicked, true);
            app.ImageSpot8.ImageSource = 'Empty.png';
            app.ImageSpot9 = uiimage(app.UIFigure);
            app.ImageSpot9.ImageClickedFcn = createCallbackFcn(app, @ImageSpot9Clicked, true);
            app.ImageSpot9.ImageSource = 'Empty.png';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.XRobotPanel = uipanel(app.UIFigure);
            app.XRobotPanel.Title = 'X Robot';
            app.PortInput_X = uieditfield(app.XRobotPanel, 'text');
            app.SerialOutput_X = uitextarea(app.XRobotPanel);
            app.PortNumberLabel_X = uilabel(app.XRobotPanel);
            app.PortNumberLabel_X.Text = 'Port Number';
            app.ConnectButton_X = uibutton(app.XRobotPanel, 'push');
            app.ConnectButton_X.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_XPushed, true);
            app.ConnectButton_X.Text = 'Connect';
            app.SerialOutputLabel_X = uilabel(app.XRobotPanel);
            app.SerialOutputLabel_X.Text = 'Serial Output';
            app.ORobotPanel = uipanel(app.UIFigure);
            app.ORobotPanel.Title = 'O Robot';
            app.PortInput_O = uieditfield(app.ORobotPanel, 'text');
            app.SerialOutput_O = uitextarea(app.ORobotPanel);
            app.PortNumberLabel_O = uilabel(app.ORobotPanel);
            app.PortNumberLabel_O.Text = 'Port Number';
            app.ConnectButton_O = uibutton(app.ORobotPanel, 'push');
            app.ConnectButton_O.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_OPushed, true);
            app.ConnectButton_O.Text = 'Connect';
            app.SerialOutputLabel_O = uilabel(app.ORobotPanel);
            app.SerialOutputLabel_O.Text = 'Serial Output';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeltaZGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end